classdef Robot2R_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Robot2R_FKAppUIFigure  matlab.ui.Figure
        ymLabel                matlab.ui.control.Label
        xmLabel                matlab.ui.control.Label
        degreesLabel_2         matlab.ui.control.Label
        degreesLabel           matlab.ui.control.Label
        Theta2                 matlab.ui.control.Slider
        Theta2Label            matlab.ui.control.Label
        Theta1                 matlab.ui.control.Slider
        Theta1Label            matlab.ui.control.Label
        y                      matlab.ui.control.NumericEditField
        yLabel                 matlab.ui.control.Label
        x                      matlab.ui.control.NumericEditField
        xLabel                 matlab.ui.control.Label
        UIAxes                 matlab.ui.control.UIAxes
    end

properties (Access = private)
    L1 = 1.0;    % Length of link 1 (meters)
    L2 = 0.6;    % Length of link 2 (meters)
end
    
    methods (Access = private)

    function updateRobot(app)

        % Read current slider values
        theta1 = deg2rad(app.Theta1.Value);  % convert ° → radians
        theta2 = deg2rad(app.Theta2.Value);

        
        x_j2  = app.L1 * cos(theta1);
        y_j2  = app.L1 * sin(theta1);
        x_end = app.L1*cos(theta1) + app.L2*cos(theta1+theta2);
        y_end = app.L1*sin(theta1) + app.L2*sin(theta1+theta2);

        % Clear and redraw 
        cla(app.UIAxes);
        hold(app.UIAxes, 'on');
        grid(app.UIAxes, 'on');
        axis(app.UIAxes, 'equal');
        axis(app.UIAxes, [-2 2 -2 2]);

        % Draw links
        plot(app.UIAxes, [0, x_j2],    [0, y_j2],    ...
             'b-', 'LineWidth', 5);
        plot(app.UIAxes, [x_j2, x_end],[y_j2, y_end], ...
             'r-', 'LineWidth', 5);

        % Draw joints
        plot(app.UIAxes, 0,     0,     'ko', ...
             'MarkerSize',14, 'MarkerFaceColor','k');
        plot(app.UIAxes, x_j2,  y_j2,  'bo', ...
             'MarkerSize',12, 'MarkerFaceColor','b');
        plot(app.UIAxes, x_end, y_end, 'r*', ...
             'MarkerSize',16, 'LineWidth', 2);

        % Workspace boundary (faint circle)
        th = linspace(0, 2*pi, 200);
        plot(app.UIAxes, ...
             (app.L1+app.L2)*cos(th), ...
             (app.L1+app.L2)*sin(th), ...
             'k--', 'LineWidth', 0.5, 'Color', [0.7 0.7 0.7]);

        % Title with current angles
        title(app.UIAxes, ...
            sprintf('\\theta_1=%.1f°   \\theta_2=%.1f°', ...
            app.Theta1.Value, app.Theta2.Value), ...
            'FontSize', 12);

        % Update coordinate display 
        app.x.Value = x_end;
        app.y.Value = y_end;

    end  % updateRobot

end  % methods

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.updateRobot();
        end

        % Value changed function: Theta1
        function Theta1ValueChanged(app, event)
            app.updateRobot();
        end

        % Value changed function: Theta2
        function Theta2ValueChanged(app, event)
            app.updateRobot();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Robot2R_FKAppUIFigure = uifigure('Visible', 'off');
            app.Robot2R_FKAppUIFigure.Name = 'Robot2R_FK App';
            app.UIAxes = uiaxes(app.Robot2R_FKAppUIFigure);
            title(app.UIAxes, '2R Robot View')
            xlabel(app.UIAxes, 'X (m)')
            ylabel(app.UIAxes, 'Y (m)')
            zlabel(app.UIAxes, 'Z')
            app.xLabel = uilabel(app.Robot2R_FKAppUIFigure);
            app.xLabel.Text = 'x';
            app.x = uieditfield(app.Robot2R_FKAppUIFigure, 'numeric');
            app.x.Editable = 'off';
            app.yLabel = uilabel(app.Robot2R_FKAppUIFigure);
            app.yLabel.Text = 'y';
            app.y = uieditfield(app.Robot2R_FKAppUIFigure, 'numeric');
            app.y.Editable = 'off';
            app.Theta1Label = uilabel(app.Robot2R_FKAppUIFigure);
            app.Theta1Label.Text = 'Theta1';
            app.Theta1 = uislider(app.Robot2R_FKAppUIFigure);
            app.Theta1.Limits = [-180 180];
            app.Theta1.ValueChangedFcn = createCallbackFcn(app, @Theta1ValueChanged, true);
            app.Theta2Label = uilabel(app.Robot2R_FKAppUIFigure);
            app.Theta2Label.Text = 'Theta2';
            app.Theta2 = uislider(app.Robot2R_FKAppUIFigure);
            app.Theta2.Limits = [-180 180];
            app.Theta2.ValueChangedFcn = createCallbackFcn(app, @Theta2ValueChanged, true);
            app.degreesLabel = uilabel(app.Robot2R_FKAppUIFigure);
            app.degreesLabel.Text = 'θ1 (degrees)';
            app.degreesLabel_2 = uilabel(app.Robot2R_FKAppUIFigure);
            app.degreesLabel_2.Text = 'θ2 (degrees)';
            app.xmLabel = uilabel(app.Robot2R_FKAppUIFigure);
            app.xmLabel.Text = 'x(m)';
            app.ymLabel = uilabel(app.Robot2R_FKAppUIFigure);
            app.ymLabel.Text = 'y(m)';
            % Show the figure after all components are created
            app.Robot2R_FKAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Robot2R_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Robot2R_FKAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Robot2R_FKAppUIFigure)
        end
    end
end